classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StopButton   matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isRunning logical = false  % Flag to control acquisition loop
        daqObj                   % DAQ object
        LivePlotLine             % Handle to animated line for real-time plotting
        TimestampsFIFOBuffer     % Circular buffer for timestamps
        DataFIFOBuffer           % Circular buffer for data
        BufferSize = 5000         % Maximum size of FIFO buffers
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: StartButton, UIAxes
        function StartButtonPushed(app, event)
          disp("Start button pressed");
            
            % Initialize DAQ object
            app.daqObj = daq("ni");
            ch1 = addinput(app.daqObj, "cDAQ1Mod1", "ai0", "Accelerometer");
            ch1.Sensitivity = 0.05; % Sensitivity in V/unit
            app.daqObj.Rate = 51200; % Sampling rate

            % Initialize buffers and animated line
            app.TimestampsFIFOBuffer = nan(1, app.BufferSize);
            app.DataFIFOBuffer = nan(1, app.BufferSize);
            app.LivePlotLine = animatedline(app.UIAxes, 'Color', 'b', 'LineWidth', 1.5);

            % Set axis labels
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');

            % Set running flag
            app.isRunning = true;

            % Acquisition and plotting loop
            while app.isRunning
                try
                    % Acquire data from DAQ
                    newData = read(app.daqObj, seconds(0.1)); % Adjust time duration


                    % Update FIFO buffers
                    numNew = numel(newData.Time);
                    app.TimestampsFIFOBuffer = circshift(app.TimestampsFIFOBuffer, -numNew);
                    app.DataFIFOBuffer = circshift(app.DataFIFOBuffer, -numNew);
                    app.TimestampsFIFOBuffer(end-numNew+1:end) = datenum(newData.Time);
                    app.DataFIFOBuffer(end-numNew+1:end) = newData.Variables;

                    % Update plot
                    %addpoints(app.plotHandle, app.TimestampsFIFOBuffer, app.DataFIFOBuffer);
                    %drawnow;

                    % Update animated line with new data
                    addpoints(app.LivePlotLine, newData.Time, newData.Variables);
                    drawnow limitrate; % Efficient plot updating

                catch ME
                    disp(['Error: ', ME.message]);
                    break;
                end

                pause(0.01); % Allow UI to remain responsive
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           % Stop acquisition loop
            app.isRunning = false;

            % Clear DAQ object
            if ~isempty(app.daqObj)
                clear app.daqObj;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Live Data')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end