classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SaveButton   matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        StopButton   matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        d              % DataAcquisition object
        isRunning      % Flag for acquisition loop
        acquiredData   % Property to store acquired data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
    try
        % Create Data Acquisition Object
        app.d = daq("ni");
        ch1 = addinput(app.d, "cDAQ1Mod1", "ai0", "Accelerometer");
        ch1.Sensitivity = 0.05;

        % Acquire data for 1 second
        data = read(app.d, seconds(1));  % This will acquire data for 1 second

        % Plot data on UIAxes
        plot(app.UIAxes, data.Time, data.Variables);
        xlabel(app.UIAxes, "Time (s)");
        ylabel(app.UIAxes, "Amplitude");
        drawnow;

        % You can also store the data for saving later
        app.acquiredData = data;

    catch ME
        % Show error message if there's an issue with data acquisition
        uialert(app.UIFigure, ['Error: ', ME.message], 'Error', 'Icon', 'error');
        disp(data)
    end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop acquisition
            app.isRunning = false;

            % Clean up
            if ~isempty(app.d)
                clear app.d;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
    try
        % Check if data is available
        if isempty(app.acquiredData)
            uialert(app.UIFigure, 'No data to save. Please acquire data first.', 'Warning', 'Icon', 'warning');
            return;
        end

        % Prompt user to choose a file location
        [file, path] = uiputfile('*.mat', 'Save Data As');
        if isequal(file, 0) % User canceled
            return;
        end

        % Save data as MAT-file
        fullPath = fullfile(path, file);
        save(fullPath, 'app.acquiredData');

        % Notify user
        uialert(app.UIFigure, 'Data saved successfully!', 'Success', 'Icon', 'success');

    catch ME
        % Display error if saving fails
        uialert(app.UIFigure, 'Failed to save data. Please try again.', 'Error', 'Icon', 'error');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end