classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileNameField           matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        daqObject              % DataAcquisition object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.daqObject = daq("ni");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
    % Add Channels
    if isempty(app.daqObject.Channels)
        % Add Channels
        ch1 = addinput(app.daqObject, "cDAQ1Mod1", "ai0", "Accelerometer");
        ch1.Sensitivity = 5.000000e-02;
    end

    % Set DataAcquisition Rate
    app.daqObject.Rate = 51200;

    % Read Data
    app.acquiredData = read(app.daqObject, seconds(1));

    % Plot Data
    plot(app.UIAxes, app.acquiredData.Time, app.acquiredData.Variables);
    xlabel(app.UIAxes, "Time (s)");
    ylabel(app.UIAxes, "Amplitude");
    legend(app.UIAxes, app.acquiredData.Properties.VariableNames, "Interpreter", "none");

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            fileName = app.FileNameField.Value;
        if isempty(fileName)
            uialert(app.UIFigure, 'Please enter a file name.', 'Error');
        else
            acquiredData = app.acquiredData; % Extract the data
            save(fileName, 'acquiredData'); % Save the data
            uialert(app.UIFigure, 'Data saved successfully.', 'Success');
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data Acquisition')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end