classdef app3004 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        LoadButton              matlab.ui.control.Button
        ResultTextArea          matlab.ui.control.TextArea
        ResultTextAreaLabel     matlab.ui.control.Label
        FileNameLabel           matlab.ui.control.Label
        FileNameField           matlab.ui.control.EditField
        FileNameEditFieldLabel  matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

 
    properties (Access = private)
        daqObject
        acquiredData
        net             % Property to store the neural network
        buttonState
    end
    
    methods (Access = private)
        
        function processedData = preprocessData(app, acquiredData, ~)
            % Segment parameters
            win_len = 25600; % Length of each segment 
            colormap = jet(256);

            % Signal length and filter bank configuration
            fb = cwtfilterbank('SignalLength', win_len, 'Wavelet', 'amor', 'VoicesPerOctave', 48);


            % Segmentation and signal extraction
    % Handle acquiredData differently based on button state
    if strcmp(app.buttonState, 'start')
        bfsignal = acquiredData{1:win_len, 1}; % Start processing
    else
        bfsignal = acquiredData(1:win_len, 1);
    end

    % Perform Continuous Wavelet Transform (CWT) and process
    cfs = abs(fb.wt(bfsignal));                     % Perform CWT
    im = ind2rgb(im2uint8(cfs), colormap); % Convert to RGB image
    im = im - min(im(:));                           % Normalize
    im = im / max(im(:));                           % Normalize
    im = imresize(im, [227, 227]);                  % Resize image

    % Return processed image
    processedData = im;
    
    % Debugging outputs
    disp(size(processedData)); % Print size of processed data
    disp(class(processedData)); % Print type of processed data
    disp('Preprocessed data size:');
           
        end

   
        function setPlotLabels(app)
            xlabel(app.UIAxes, "Time (s)");
            ylabel(app.UIAxes, "Amplitude");
        end
        

         function predictions = classifyData(app, preprocessedData)
            %disp('classifyData called'); % Debug statement
            %disp('Preprocessed data size:');
            %disp(size(preprocessedData)); % Debug statement
            %disp('Preprocessed data type:');
            %disp(class(preprocessedData)); % Debug statement

            % Check if the network is loaded
    if isempty(app.net)
        error('Neural network is not loaded!');
    end

    % Perform classification
    try
        predictions = classify(app.net, preprocessedData);
    catch ME
        disp('Error during classification:');
        disp(ME.message);
        predictions = 'Error'; % Handle gracefully
    end
            %disp('Predictions:');
            %disp(predictions); % Debug statement
          end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.daqObject = daq("ni");

            mlfile = 'C:\Users\Barat\OneDrive - Universiti Teknologi Malaysia (UTM)\Documents\MATLAB\Examples\R2024a\daq\LiveDataAcquisitionAppExample\barathanNet.mat';
disp("Network input layer requirements:");
analyzeNetwork(app.net); % Display the network structure in a separate window

            if isfile(mlfile)
                data = load(mlfile);
                [~, fileName, ext] = fileparts(mlfile);
                app.FileNameLabel.Text = ['Loaded: ', fileName, ext];
                disp(data);
                app.net = data.classifyNet; % Import the model into the GUI
            else
                app.FileNameLabel.Text = 'File not found.';
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            app.buttonState = 'start';
% Add Channels
    if isempty(app.daqObject.Channels)
        % Add Channels
        ch1 = addinput(app.daqObject, "cDAQ1Mod1", "ai0", "Accelerometer");
        ch1.Sensitivity = 5.000000e-02;
    end

    % Set DataAcquisition Rate
    app.daqObject.Rate = 51200;

    % Read Data
    app.acquiredData = read(app.daqObject, seconds(10));

    % Plot Data
    plot(app.UIAxes, app.acquiredData.Time, app.acquiredData.Variables);
    setPlotLabels(app);
    legend(app.UIAxes, app.acquiredData.Properties.VariableNames, "Interpreter", "none");

    % Process the acquired data
    processedData = app.preprocessData(app.acquiredData, app.net);
    %disp(size(processedData)); % Debug statement
    %disp(class(processedData)); % Debug statement

    % Make predictions
    prediction = app.classifyData(processedData);
    %disp(prediction); % Debug statement

    % Display results in the text box
    app.ResultTextArea.Value = char(prediction);

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            app.buttonState = 'load';
        fileName = app.FileNameField.Value;
    if isempty(fileName)
        uialert(app.UIFigure, 'Please enter a file name.', 'Error');
    else
        saveData = app.saveData;  % Assign to a local variable
        save(fileName, 'saveData');
        uialert(app.UIFigure, 'Data saved successfully.', 'Success');
    end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            disp('LoadButtonPushed called'); % Debug statement   
            [file, path] = uigetfile('*.mat', 'Select a MAT-file');
    if isequal(file, 0)
        uialert(app.UIFigure, 'No file selected.', 'Error');
    else
        fullFileName = fullfile(path, file);
        data = load(fullFileName);
        % Assuming you don't know the variable name, you can get the field names
        varNames = fieldnames(data);
        app.acquiredData = data.(varNames{1}); % Use the first variable found

        processedData = app.preprocessData(app.acquiredData, app.net);
        %disp(size(processedData)); % Debug statement
        %disp(class(processedData)); % Debug statement

        % Plot Data
        time = linspace(0, 10, length(app.acquiredData));
        plot(app.UIAxes, time, app.acquiredData);
        setPlotLabels(app);

        % Classify each image and find the most frequent prediction
        prediction = app.classifyData(processedData);
        %disp(prediction); % Debug statement to print prediction
        app.ResultTextArea.Value = char(prediction);
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data Acquisition')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameField = uieditfield(app.UIFigure, 'text');
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'File Name';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.ResultTextArea = uitextarea(app.UIFigure);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3004

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end